classdef Do_an_CK < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        GiithiuTab                      matlab.ui.container.Tab
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        MSSV21207114Label               matlab.ui.control.Label
        HvtnNguynBTunLabel              matlab.ui.control.Label
        Lp21DTV_CLC1Label               matlab.ui.control.Label
        MnhcPhngphptnhvmatlabLabel      matlab.ui.control.Label
        BococuikLabel                   matlab.ui.control.Label
        TmnghimTab_2                    matlab.ui.container.Tab
        SoLanLap                        matlab.ui.control.NumericEditField
        SlnlpEditFieldLabel_3           matlab.ui.control.Label
        TimNghiem                       matlab.ui.control.Button
        KetQuaNghiem                    matlab.ui.control.EditField
        KtqunghimEditFieldLabel_2       matlab.ui.control.Label
        SaiSo_1                         matlab.ui.control.NumericEditField
        SaischophpLabel                 matlab.ui.control.Label
        PhanLyB                         matlab.ui.control.NumericEditField
        NhpkhongphnlynghimLabel_2       matlab.ui.control.Label
        PhanLyA                         matlab.ui.control.NumericEditField
        PhuongTrinh_2                   matlab.ui.control.EditField
        Nhpphngtrnh2Label_2             matlab.ui.control.Label
        ChonPPNghiem                    matlab.ui.control.DropDown
        ChnphngphptmnghimLabel_2        matlab.ui.control.Label
        PhuongTrinh_1                   matlab.ui.control.EditField
        NhpphngtrnhEditFieldLabel_2     matlab.ui.control.Label
        DoThiNghiem                     matlab.ui.control.UIAxes
        Noi_suy                         matlab.ui.container.Tab
        KtquanisuyEditField             matlab.ui.control.NumericEditField
        KtquanisuyEditFieldLabel        matlab.ui.control.Label
        Kq_da_thuc                      matlab.ui.control.EditField
        KtquaathcnisuyEditFieldLabel    matlab.ui.control.Label
        Gia_tri_noi_suy                 matlab.ui.control.NumericEditField
        NhpgiatricnnisuyEditFieldLabel  matlab.ui.control.Label
        Nhap_y                          matlab.ui.control.EditField
        NhpyEditFieldLabel              matlab.ui.control.Label
        Nhap_x_noi                      matlab.ui.control.EditField
        NhpxEditFieldLabel              matlab.ui.control.Label
        P_P_noi_suy                     matlab.ui.control.DropDown
        PhngphapnisuyDropDownLabel      matlab.ui.control.Label
        NhpButton_2                     matlab.ui.control.StateButton
        UIAxes_2                        matlab.ui.control.UIAxes
        HiquyTab                        matlab.ui.container.Tab
        KtquahiquyEditField             matlab.ui.control.NumericEditField
        KtquahiquyEditFieldLabel        matlab.ui.control.Label
        Kq_da_thuc_2                    matlab.ui.control.EditField
        KtquaathchiquyLabel             matlab.ui.control.Label
        NhpButton_4                     matlab.ui.control.StateButton
        Gia_tri_hoi                     matlab.ui.control.NumericEditField
        NhpgiatricndoanEditFieldLabel   matlab.ui.control.Label
        Nhap_y_hoi                      matlab.ui.control.EditField
        NhpyEditField_2Label            matlab.ui.control.Label
        Nhap_x_hoi                      matlab.ui.control.EditField
        NhpxEditField_2Label            matlab.ui.control.Label
        P_P_hoi_quy                     matlab.ui.control.DropDown
        PhngphaphiquyDropDownLabel      matlab.ui.control.Label
        UIAxes_4                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function NhpButton_3ValueChanged(app, event)
                fx = matlabFunction(str2sym(app.PhuongTrinh_1.Value));
                fp = matlabFunction(str2sym(app.PhuongTrinh_2.Value));
                a = app.Muc_a.Value;
                b = app.Muc_b.Value;
                ss = app.Saiso.Value;

                switch app.Phuong_phap.Value
                    case 'Day cung'
                          [nghiem, soLanLap] = Daycung(fx, a, b, ss);
                    case 'Chia doi'
                           [nghiem, soLanLap] = chiadoi(fx, a, b, ss);
                    case 'Lap'                      
                           [nghiem, soLanLap] = lap(fx, fp, a, b, ss);
                    case 'Newton(tiep tuyen)'
                           [nghiem, soLanLap] = tieptuyen(fx, a, b, ss);
                    otherwise
                            nghiem = 0; soLanLap = 0;
                    end

                    app.Kq_nghiem.Value = num2str(nghiem);
                    app.SLL.Value = soLanLap;

                    t = -5:0.01:5;
                    plot(app.UIAxes_3,[-5 5],[0 0],'black',t,fx(t),'b',nghiem, 0, 'ro');

        end

        % Callback function
        function KtquaButton_2Pushed(app, event)
            f = app.NhphamsEditField_2.Value;
            fx = str2func(['@(x)',f]);
            a = app.Muc_a_2.Value;
            b = app.Muc_b_2.Value;
            n = app.Nhap_n.Value;
            switch(app.PhngphaptichphnDropDown.Value)
                case 'Hình thang'
                    y = TichPhanHinhThang(fx,a,b,n);
                    app.Ket_qua_2.Value = num2str(y);
                case 'Simpson 1/3'
                    y = TichPhanSimpson(fx,a,b,n);
                    app.Ket_qua_2.Value = num2str(y);
                case 'Simpson 3/8'
                    y = TichPhanSimpson38(fx,a,b,n);
                    app.Ket_qua_2.Value = num2str(y);
            end
        end

        % Callback function
        function Phuong_phapValueChanged(app, event)
            if strcmp(app.Phuong_phap.Value, 'Lap')
                app.gxEditFieldLabel.Visible = 'on';
                app.Gx.Visible = 'on';
                app.nhap_gx.Visible = 'on';
            else
                app.gxEditFieldLabel.Visible = 'off';
                app.Gx.Visible = 'off';
                app.nhap_gx.Visible = 'off';
            end
        end

        % Callback function
        function Kq_dao_hamPushed(app, event)
            fx = str2func(['@(x)',app.Ham_dh.Value]);
            h = app.Nhap_h.Value;
            h = str2double(h);
            x_c = app.Nhap_x_dh_2.Value;
            if isnan(h)
                h = x_c(1,2) - x_c(1,1);
            end
            x_ct = app.Gia_tri_tinh_dh.Value;
            pp = app.Phuong_Phap_Dao_ham.Value;
            switch (pp)
                case "Xấp xỉ trung tâm"
                    pp = "trung tam";
                case "Xấp xỉ tiến"
                    pp = "tien";
                case "Xấp xỉ lui"
                    pp = "lui";
            end
            switch (app.Sai_so_dh.Value)
                case "O(h)"
                    if (pp == "trung tam")
                        app.Ket_qua_dao_ham.Value = "Không thể xấp xỉ trung tâm ở sai số O(h). Hãy chọn lại";
                    else
                       ydh =  DaoHamTaylor1(x_ct,fx,h,pp);
                       app.Ket_qua_dao_ham.Value = num2mstr(ydh);
                    end
                case "O(h^2)"
                     ydh =  DaoHamTaylor2(x_ct,fx,h,pp);
                     app.Ket_qua_dao_ham.Value = num2mstr(ydh);
            end
        end

        % Callback function
        function Sai_so_dhValueChanged(app, event)
            
        end

        % Value changed function: NhpButton_2
        function NhpButton_2ValueChanged(app, event)
                xa = app.Nhap_x_noi.Value;  xa = str2num(xa);
                ya = app.Nhap_y.Value;      ya = str2num(ya);
                x = app.Gia_tri_noi_suy.Value;
                switch (app.P_P_noi_suy.Value)
                    case 'Lagrange'
                        dt_noi_suy = lagrangeinterp(xa,ya);
                        dt_noi_suy = expand(dt_noi_suy);
                    case 'Newton'
                        dt_noi_suy = newtoninterp(xa,ya);
                        dt_noi_suy = expand(dt_noi_suy);
                    otherwise
                        error('Chua nhap x hay nhap y. Hay kiem tra lại cac nghiem');
                end
                app.Kq_da_thuc.Value = string(dt_noi_suy);
                dt_noi_suy = matlabFunction(dt_noi_suy);
                app.KtquanisuyEditField.Value = dt_noi_suy(x);
                x_a = xa(1,1):xa(1,length(xa));
                plot(app.UIAxes_2,x_a,dt_noi_suy(x_a),x, dt_noi_suy(x), 'ro');
        end

        % Value changed function: NhpButton_4
        function NhpButton_4ValueChanged(app, event)
                x_h = app.Nhap_x_hoi.Value; x_h = str2num(x_h);
                y_h = app.Nhap_y_hoi.Value; y_h = str2num(y_h);
                x_tim = app.Gia_tri_hoi.Value;
                syms x;
                switch (app.P_P_hoi_quy.Value)
                    case 'Hàm tuyến tính'
                        [a1, a0] =Regress(x_h,y_h);
                        a = a1; b = a0;
                        yo = a*x + b;
                    case 'Hàm a*e^(bx)'
                        Y = log10(y_h);
                        [a1, a0] =Regress(x_h,Y);
                        a = exp(a0);
                        b = a1;
                        yo = (exp(a0)).*exp(a1*x);
                    case 'Hàm a*x^b'
                        X = log10(x_h);
                        Y = log10(y_h);
                        [a1, a0] =Regress(X,Y);
                        a = 10^(a0);
                        b = a1;
                        yo = (10^(a0)).*x.^a1;
                    case 'Hàm logarit'
                        X = 1./x_h;
                        Y = 1./y_h;
                        [a1, a0] =Regress(X,Y);
                        a = 1./a0;
                        b = a1./a0;
                        yo = 1./a0*(x/(a1/a0 + x));
                end
                app.Kq_da_thuc_2.Value = string(yo);
                yo = matlabFunction(yo);
                app.KtquahiquyEditField.Value = yo(x_tim);
                x_v = x_h(1,1):x_h(1,length(x_h));
                plot(app.UIAxes_4,x_v,yo(x_v),x_tim, yo(x_tim), 'ro');
        end

        % Button pushed function: TimNghiem
        function TimNghiemButtonPushed(app, event)
            
            fp = matlabFunction(str2sym(app.PhuongTrinh_2.Value));

            a = app.PhanLyA.Value;
            b = app.PhanLyB.Value;
            es = app.SaiSo_1.Value;
            pp = app.ChonPPNghiem.Value;

            [KQ, n, x] = FuncTimNghiem(fx, fp, a, b, es, pp);

            cla(app.DoThiNghiem);
            grid(app.DoThiNghiem, 'on');
            hold(app.DoThiNghiem, 'on');
            plot(app.DoThiNghiem, x, zeros(1, 100), 'k');
            if(pp ~= "Chia đôi")
                plot(app.DoThiNghiem, zeros(1, 100), x, 'k');
            end
            if(pp == "Lặp")
                plot(app.DoThiNghiem, x, fp(x),'b-', x, x,'g-', KQ, fp(KQ), 'r*');
            else
                plot(app.DoThiNghiem, x, fx(x),'b-', KQ, fx(KQ), 'r*');
            end

            app.KetQuaNghiem.Value = num2str(KQ);
            app.SoLanLap.Value = n;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GiithiuTab = uitab(app.TabGroup);
            app.GiithiuTab.Title = 'Giới thiệu';
            app.BococuikLabel = uilabel(app.GiithiuTab);
            app.BococuikLabel.Text = 'Báo cáo cuối kỳ';
            app.MnhcPhngphptnhvmatlabLabel = uilabel(app.GiithiuTab);
            app.MnhcPhngphptnhvmatlabLabel.Text = 'Môn học: Phương pháp tính và matlab';
            app.Lp21DTV_CLC1Label = uilabel(app.GiithiuTab);
            app.Lp21DTV_CLC1Label.Text = 'Lớp: 21DTV_CLC1';
            app.HvtnNguynBTunLabel = uilabel(app.GiithiuTab);
            app.HvtnNguynBTunLabel.Text = 'Họ và tên: Nguyễn Bá Tuấn';
            app.MSSV21207114Label = uilabel(app.GiithiuTab);
            app.MSSV21207114Label.Text = 'MSSV: 21207114';
            app.Image = uiimage(app.GiithiuTab);
            app.Image.ImageSource = 'NguyenBaTuan.jpg';
            app.Image2 = uiimage(app.GiithiuTab);
            app.Image2.ImageSource = 'logo3.png';
            app.TmnghimTab_2 = uitab(app.TabGroup);
            app.TmnghimTab_2.Title = 'Tìm nghiệm';
            app.DoThiNghiem = uiaxes(app.TmnghimTab_2);
            title(app.DoThiNghiem, 'Vẽ đồ thị hàm số')
            xlabel(app.DoThiNghiem, 'X')
            ylabel(app.DoThiNghiem, 'Y')
            zlabel(app.DoThiNghiem, 'Z')
            app.NhpphngtrnhEditFieldLabel_2 = uilabel(app.TmnghimTab_2);
            app.NhpphngtrnhEditFieldLabel_2.Text = 'Nhập phương trình';
            app.PhuongTrinh_1 = uieditfield(app.TmnghimTab_2, 'text');
            app.PhuongTrinh_1.Value = 'x - sin(x) - 0.25';
            app.ChnphngphptmnghimLabel_2 = uilabel(app.TmnghimTab_2);
            app.ChnphngphptmnghimLabel_2.Text = 'Chọn phương pháp tìm nghiệm';
            app.ChonPPNghiem = uidropdown(app.TmnghimTab_2);
            app.ChonPPNghiem.Items = {'Chia đôi', 'Lặp', 'Newton (Tiếp tuyến)'};
            app.ChonPPNghiem.Value = 'Chia đôi';
            app.Nhpphngtrnh2Label_2 = uilabel(app.TmnghimTab_2);
            app.Nhpphngtrnh2Label_2.Text = 'Nhập phương trình 2';
            app.PhuongTrinh_2 = uieditfield(app.TmnghimTab_2, 'text');
            app.PhuongTrinh_2.Value = 'sin(x) + 0.25';
            app.PhanLyA = uieditfield(app.TmnghimTab_2, 'numeric');
            app.PhanLyA.Value = -1;
            app.NhpkhongphnlynghimLabel_2 = uilabel(app.TmnghimTab_2);
            app.NhpkhongphnlynghimLabel_2.Text = 'Nhập khoảng phân ly';
            app.PhanLyB = uieditfield(app.TmnghimTab_2, 'numeric');
            app.PhanLyB.Value = 2;
            app.SaischophpLabel = uilabel(app.TmnghimTab_2);
            app.SaischophpLabel.Text = 'Sai số cho phép';
            app.SaiSo_1 = uieditfield(app.TmnghimTab_2, 'numeric');
            app.SaiSo_1.Value = 0.005;
            app.KtqunghimEditFieldLabel_2 = uilabel(app.TmnghimTab_2);
            app.KtqunghimEditFieldLabel_2.Text = 'Kết quả nghiệm';
            app.KetQuaNghiem = uieditfield(app.TmnghimTab_2, 'text');
            app.TimNghiem = uibutton(app.TmnghimTab_2, 'push');
            app.TimNghiem.ButtonPushedFcn = createCallbackFcn(app, @TimNghiemButtonPushed, true);
            app.TimNghiem.Text = 'Tìm Nghiệm';
            app.SlnlpEditFieldLabel_3 = uilabel(app.TmnghimTab_2);
            app.SlnlpEditFieldLabel_3.Text = 'Số lần lặp';
            app.SoLanLap = uieditfield(app.TmnghimTab_2, 'numeric');
            app.Noi_suy = uitab(app.TabGroup);
            app.Noi_suy.Title = 'Nội suy';
            app.UIAxes_2 = uiaxes(app.Noi_suy);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.NhpButton_2 = uibutton(app.Noi_suy, 'state');
            app.NhpButton_2.ValueChangedFcn = createCallbackFcn(app, @NhpButton_2ValueChanged, true);
            app.NhpButton_2.Text = 'Nhập';
            app.PhngphapnisuyDropDownLabel = uilabel(app.Noi_suy);
            app.PhngphapnisuyDropDownLabel.Text = 'Phương pháp nội suy';
            app.P_P_noi_suy = uidropdown(app.Noi_suy);
            app.P_P_noi_suy.Items = {'Newton', 'Lagrange'};
            app.P_P_noi_suy.Value = 'Lagrange';
            app.NhpxEditFieldLabel = uilabel(app.Noi_suy);
            app.NhpxEditFieldLabel.Text = 'Nhập x';
            app.Nhap_x_noi = uieditfield(app.Noi_suy, 'text');
            app.Nhap_x_noi.Value = '1 2 3 4 7';
            app.NhpyEditFieldLabel = uilabel(app.Noi_suy);
            app.NhpyEditFieldLabel.Text = 'Nhập y';
            app.Nhap_y = uieditfield(app.Noi_suy, 'text');
            app.Nhap_y.Value = '17 17.5 76 210.5 1970';
            app.NhpgiatricnnisuyEditFieldLabel = uilabel(app.Noi_suy);
            app.NhpgiatricnnisuyEditFieldLabel.Text = 'Nhập giá trị cần nội suy';
            app.Gia_tri_noi_suy = uieditfield(app.Noi_suy, 'numeric');
            app.Gia_tri_noi_suy.Value = 5;
            app.KtquaathcnisuyEditFieldLabel = uilabel(app.Noi_suy);
            app.KtquaathcnisuyEditFieldLabel.Text = 'Kết quả đa thức nội suy';
            app.Kq_da_thuc = uieditfield(app.Noi_suy, 'text');
            app.KtquanisuyEditFieldLabel = uilabel(app.Noi_suy);
            app.KtquanisuyEditFieldLabel.Text = 'Kết quả nội suy';
            app.KtquanisuyEditField = uieditfield(app.Noi_suy, 'numeric');
            app.HiquyTab = uitab(app.TabGroup);
            app.HiquyTab.Title = 'Hồi quy';
            app.UIAxes_4 = uiaxes(app.HiquyTab);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            app.PhngphaphiquyDropDownLabel = uilabel(app.HiquyTab);
            app.PhngphaphiquyDropDownLabel.Text = 'Phương pháp hồi quy';
            app.P_P_hoi_quy = uidropdown(app.HiquyTab);
            app.P_P_hoi_quy.Items = {'Hàm tuyến tính', 'Hàm a*e^(bx)', 'Hàm a*x^b', 'Hàm logarit'};
            app.P_P_hoi_quy.Value = 'Hàm tuyến tính';
            app.NhpxEditField_2Label = uilabel(app.HiquyTab);
            app.NhpxEditField_2Label.Text = 'Nhập x';
            app.Nhap_x_hoi = uieditfield(app.HiquyTab, 'text');
            app.Nhap_x_hoi.Value = '1 2 3 4 5 6 7 8 9';
            app.NhpyEditField_2Label = uilabel(app.HiquyTab);
            app.NhpyEditField_2Label.Text = 'Nhập y';
            app.Nhap_y_hoi = uieditfield(app.HiquyTab, 'text');
            app.Nhap_y_hoi.Value = '1 1.5 2 3 4 5 8 10 13';
            app.NhpgiatricndoanEditFieldLabel = uilabel(app.HiquyTab);
            app.NhpgiatricndoanEditFieldLabel.Text = 'Nhập giá trị cần dự đoán';
            app.Gia_tri_hoi = uieditfield(app.HiquyTab, 'numeric');
            app.Gia_tri_hoi.Value = 4.5;
            app.NhpButton_4 = uibutton(app.HiquyTab, 'state');
            app.NhpButton_4.ValueChangedFcn = createCallbackFcn(app, @NhpButton_4ValueChanged, true);
            app.NhpButton_4.Text = 'Nhập';
            app.KtquaathchiquyLabel = uilabel(app.HiquyTab);
            app.KtquaathchiquyLabel.Text = 'Kết quả đa thức hồi quy';
            app.Kq_da_thuc_2 = uieditfield(app.HiquyTab, 'text');
            app.KtquahiquyEditFieldLabel = uilabel(app.HiquyTab);
            app.KtquahiquyEditFieldLabel.Text = 'Kết quả hồi quy';
            app.KtquahiquyEditField = uieditfield(app.HiquyTab, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Do_an_CK

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end